classdef re_id_trdp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PersonReIdetificationBenchmarkLabel  matlab.ui.control.Label
        PersonReIdetificationBenchmarkLabel_2  matlab.ui.control.Label
        PreProcessingButtonGroup      matlab.ui.container.ButtonGroup
        OriginalDataButton            matlab.ui.control.ToggleButton
        MidwayImageQualizationButton  matlab.ui.control.ToggleButton
        LuminanceRemappingButton      matlab.ui.control.ToggleButton
        VIPeRDatasetSingleShotReIDScenarioLabel  matlab.ui.control.Label
        FeatureLearningButtonGroup    matlab.ui.container.ButtonGroup
        GOGButton                     matlab.ui.control.ToggleButton
        LDFVButton                    matlab.ui.control.ToggleButton
        MetricLearningButtonGroup     matlab.ui.container.ButtonGroup
        XQDAButton                    matlab.ui.control.ToggleButton
        LFDAButton                    matlab.ui.control.ToggleButton
        RankingButtonGroup            matlab.ui.container.ButtonGroup
        SingleShotRankingLabel        matlab.ui.control.Label
        VisualizeFailcasesButton      matlab.ui.control.StateButton
        RuntheBenchmarkButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RuntheBenchmarkButton
        function RuntheBenchmarkButtonPushed(app, event)
            vlStatus=false;
            if app.OriginalDataButton.Value
                preProp='No';
            end
            if app.MidwayImageQualizationButton.Value
                preProp='Midway';
            end
            if app.LuminanceRemappingButton.Value
                preProp='Luminance';
            end
            if app.GOGButton.Value
                featureType='GOG';
            end
            if app.LDFVButton.Value
                featureType='ldfv';
            end
            if app.XQDAButton.Value
                metricType='xqda';
            end
            if app.LFDAButton.Value
                metricType='lfda';
            end
            if app.VisualizeFailcasesButton.Value
                vlStatus=true;
            end
            run_experiment_benchmark_updated(preProp,featureType,metricType,vlStatus);
        end

        % Selection changed function: PreProcessingButtonGroup
        function PreProcessingButtonGroupSelectionChanged(app, event)
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PersonReIdetificationBenchmarkLabel = uilabel(app.UIFigure);
            app.PersonReIdetificationBenchmarkLabel.Text = {'S. karanam, M. Gou, Z. Wu, A. Rates-Borras, O. Camps and R. J. Radke, "A Systematic Evaluation and Benchmark for Person '; 'Re-Identification: Features, Metrics, and Datasets," in IEEE Transactions on Pattern Analysis and Machine Intelligence, vol. 41,'; 'no. 3, pp. 523-536, 1 March 2019. doi: 10.1109/TPAMI.2018.2807450. '; 'Available at: http://ieeexplore.ieee.org/stamp/stamp.jsp?tp=&arnumber=8294254&isnumber=8635379'; 'GitHub:  https://github.com/RSL-NEU/person-reid-benchmark'};
            app.PersonReIdetificationBenchmarkLabel_2 = uilabel(app.UIFigure);
            app.PersonReIdetificationBenchmarkLabel_2.Text = 'Person Re-Idetification Benchmark ';
            app.PreProcessingButtonGroup = uibuttongroup(app.UIFigure);
            app.PreProcessingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreProcessingButtonGroupSelectionChanged, true);
            app.PreProcessingButtonGroup.Title = 'Pre-Processing';
            app.OriginalDataButton = uitogglebutton(app.PreProcessingButtonGroup);
            app.OriginalDataButton.Text = 'Original Data';
            app.OriginalDataButton.Value = true;
            app.MidwayImageQualizationButton = uitogglebutton(app.PreProcessingButtonGroup);
            app.MidwayImageQualizationButton.Text = 'Midway Image Qualization';
            app.LuminanceRemappingButton = uitogglebutton(app.PreProcessingButtonGroup);
            app.LuminanceRemappingButton.Text = 'Luminance Remapping';
            app.VIPeRDatasetSingleShotReIDScenarioLabel = uilabel(app.UIFigure);
            app.VIPeRDatasetSingleShotReIDScenarioLabel.Text = 'VIPeR Dataset: Single Shot Re-ID Scenario';
            app.FeatureLearningButtonGroup = uibuttongroup(app.UIFigure);
            app.FeatureLearningButtonGroup.Title = 'Feature Learning';
            app.GOGButton = uitogglebutton(app.FeatureLearningButtonGroup);
            app.GOGButton.Text = 'GOG';
            app.LDFVButton = uitogglebutton(app.FeatureLearningButtonGroup);
            app.LDFVButton.Text = 'LDFV';
            app.MetricLearningButtonGroup = uibuttongroup(app.UIFigure);
            app.MetricLearningButtonGroup.Title = 'Metric Learning';
            app.XQDAButton = uitogglebutton(app.MetricLearningButtonGroup);
            app.XQDAButton.Text = 'XQDA';
            app.LFDAButton = uitogglebutton(app.MetricLearningButtonGroup);
            app.LFDAButton.Text = 'LFDA';
            app.RankingButtonGroup = uibuttongroup(app.UIFigure);
            app.RankingButtonGroup.Title = 'Ranking';
            app.SingleShotRankingLabel = uilabel(app.UIFigure);
            app.SingleShotRankingLabel.Text = 'Single Shot Ranking';
            app.VisualizeFailcasesButton = uibutton(app.UIFigure, 'state');
            app.VisualizeFailcasesButton.Text = 'Visualize Failcases';
            app.RuntheBenchmarkButton = uibutton(app.UIFigure, 'push');
            app.RuntheBenchmarkButton.ButtonPushedFcn = createCallbackFcn(app, @RuntheBenchmarkButtonPushed, true);
            app.RuntheBenchmarkButton.Text = 'Run the Benchmark';
        end
    end

    methods (Access = public)

        % Construct app
        function app = re_id_trdp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end